classdef interfazV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GRAFICARButton      matlab.ui.control.Button
        ply                 matlab.ui.control.Button
        pse                 matlab.ui.control.Button
        stp                 matlab.ui.control.Button
        Khza30KhzKnob       matlab.ui.control.Knob
        Khza30KhzKnobLabel  matlab.ui.control.Label
        Khza10KhzKnob       matlab.ui.control.Knob
        Khza10KhzKnobLabel  matlab.ui.control.Label
        Khza5KhzKnob        matlab.ui.control.Knob
        Khza5KhzKnobLabel   matlab.ui.control.Label
        Hza2KhzKnob         matlab.ui.control.Knob
        Hza2KhzKnobLabel    matlab.ui.control.Label
        Hza500HzKnob        matlab.ui.control.Knob
        Hza500HzKnobLabel   matlab.ui.control.Label
        UIAxes_salida_2     matlab.ui.control.UIAxes
        UIAxes_entrada_2    matlab.ui.control.UIAxes
        UIAxes_salida       matlab.ui.control.UIAxes
        UIAxes_entrada      matlab.ui.control.UIAxes
        UIAxes_5            matlab.ui.control.UIAxes
        UIAxes_4            matlab.ui.control.UIAxes
        UIAxes_3            matlab.ui.control.UIAxes
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

   % En la sección de propiedades de tu App
properties (Access = private)
    nombre_archivo = 'FiltroFinal.slx';
    isRunning = false;
    timerObj;
end


methods (Access = private)
    function graficar_funciones(app)
        % Filtros
            datos_senal1 = evalin('base', 'out.a1'); % Accede a la variable del workspace base
            datos_senal2 = evalin('base', 'out.b1'); % Accede a la variable del workspace base
            datos_senal3 = evalin('base', 'out.c1'); % Accede a la variable del workspace base
            datos_senal4 = evalin('base', 'out.d1'); % Accede a la variable del workspace base
            datos_senal5 = evalin('base', 'out.e1'); % Accede a la variable del workspace base
            datos_entrada = evalin('base', 'out.entrada'); % Accede a la variable del workspace base
            datos_salida = evalin('base', 'out.salida'); % Accede a la variable del workspace base
           
            %Parametrizar señales
                tiempo1 = datos_senal1.Time;
                valores1 = squeeze(datos_senal1.Data(1,:,:)); %Usar squeeze para eliminar la dimensión unitaria
                
                disp(['Tamaño de tiempo: ', num2str(size(tiempo1))]);
                disp(['Tamaño de valores: ', num2str(size(valores1))]);
                   
                tiempo2 = datos_senal2.Time;
                valores2 = squeeze(datos_senal2.Data(1,:,:)); %Usar squeeze para eliminar la dimensión unitaria
                    
                tiempo3 = datos_senal3.Time;
                valores3 = squeeze(datos_senal3.Data(1,:,:)); %Usar squeeze para eliminar la dimensión unitaria
                    
                tiempo4 = datos_senal4.Time;
                valores4 = squeeze(datos_senal4.Data(1,:,:)); %Usar squeeze para eliminar la dimensión unitaria
                    
                tiempo5 = datos_senal5.Time;
                valores5 = squeeze(datos_senal5.Data(1,:,:)); %Usar squeeze para eliminar la dimensión unitaria
                  
                %Señal entrada
            
                tiempo_entrada = datos_entrada.Time;
                valores_entrada = squeeze(datos_entrada.Data(1,:,:)); %Usar squeeze para eliminar la dimensión unitaria
                    
                %Señal_salida
            
                tiempo_salida = datos_salida.Time;
                valores_salida = squeeze(datos_salida.Data(1,:,:)); %Usar squeeze para eliminar la dimensión unitaria
            % Transformadas de Fourier
                fs = 1 / (tiempo1(2) - tiempo1(1)); % Frecuencia de muestreo (suponiendo que las señales tienen la misma frecuencia de muestreo)
                frecuencia_entrada = fft(valores_entrada);
                frecuencia_salida = fft(valores_salida);
                
            % Calculamos las frecuencias correspondientes
                N = length(valores1); % Número de puntos en la señal
                f = (0:N-1) * fs / N; % Eje de frecuencia

            plot(app.UIAxes, tiempo1, valores1); 
            plot(app.UIAxes_2, tiempo2, valores2); 
            plot(app.UIAxes_3, tiempo3, valores3); 
            plot(app.UIAxes_4, tiempo4, valores4); 
            plot(app.UIAxes_5, tiempo5, valores5); 
            plot(app.UIAxes_entrada, tiempo_entrada, valores_entrada); 
            plot(app.UIAxes_salida, tiempo_salida, valores_salida); 
            plot(app.UIAxes_entrada_2, f, abs(frecuencia_entrada));
            plot(app.UIAxes_salida_2, f, abs(frecuencia_salida));
    end
end
methods (Access = private)

    function updatePlot(app)
        disp('Funcion update')
        try
            app.graficar_funciones
        catch ME
            warning(['Error al actualizar la gráfica: ', ME.message]);
            if app.isRunning
                stop(app.timerObj);
                delete(app.timerObj);
                app.isRunning = false;
                app.StartSimulationButton.Text = 'Iniciar Simulación';
            end
        end
    end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ply
        function plylay(app, event)
            if ~app.isRunning
                app.isRunning = true;
                find_system('Name', app.nombre_archivo);
                open_system(app.nombre_archivo);
                set_param(gcs,'SimulationCommand','start');
                app.timerObj = timer('ExecutionMode', 'fixedRate', ...
                                     'Period', 0.1, ... % Ajusta la frecuencia de actualización
                                     'TimerFcn', @(src,event) app.updatePlot);
                start(app.timerObj);
            else
                app.isRunning = false;
                set_param(app.simulinkModel, 'SimulationCommand', 'stop');
                stop(app.timerObj);
                delete(app.timerObj);
            end
        end

        % Button pushed function: pse
        function Pause(app, event)
            set_param(gcs,'SimulationCommand','pause');
            pause(2)
            set_param(gcs,'SimulationCommand','continue');
        end

        % Button pushed function: stp
        function Stop(app, event)
            set_param(gcs,'SimulationCommand','stop');
            save_system(app.nombre_archivo);
            close_system(app.nombre_archivo);
        end

        % Value changing function: Hza500HzKnob
        function ganancia500hz(app, event)
            changingValue = event.Value;
            set_param('FiltroFinal/a',...
                'Gain',num2str(changingValue*0.2))
        end

        % Value changing function: Hza2KhzKnob
        function ganancia2khz(app, event)
            changingValue = event.Value;
            set_param('FiltroFinal/b',...
                'Gain',num2str(changingValue*0.2));
        end

        % Value changing function: Khza5KhzKnob
        function ganancia5khz(app, event)
            changingValue = event.Value;
            set_param('FiltroFinal/c',...
                'Gain',num2str(changingValue*0.2));
        end

        % Value changing function: Khza10KhzKnob
        function ganancia10khz(app, event)
            changingValue = event.Value;
            set_param('FiltroFinal/d',...
                'Gain',num2str(changingValue*0.2));
        end

        % Value changing function: Khza30KhzKnob
        function ganancia30khz(app, event)
            changingValue = event.Value;
            set_param('FiltroFinal/e',...
                'Gain',num2str(changingValue*0.2));
        end

        % Callback function
        function scope500hz(app, event)
            
        end

        % Button pushed function: GRAFICARButton
        function graficar(app, event)
            app.graficar_funciones
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '0Hz a 500Hz')
            xlabel(app.UIAxes, 'Tiempo (s)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '500Hz a 2Khz')
            xlabel(app.UIAxes_2, 'Tiempo (s)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '2Khz a 5Khz')
            xlabel(app.UIAxes_3, 'Tiempo (s)')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, '5Khz a 10Khz')
            xlabel(app.UIAxes_4, 'Tiempo (s)')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, '10Khz a 30Khz')
            xlabel(app.UIAxes_5, 'Tiempo (s)')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_entrada = uiaxes(app.UIFigure);
            title(app.UIAxes_entrada, 'Señal Entrada')
            xlabel(app.UIAxes_entrada, 'Tiempo (s)')
            zlabel(app.UIAxes_entrada, 'Z')
            app.UIAxes_salida = uiaxes(app.UIFigure);
            title(app.UIAxes_salida, 'Señal Salida')
            xlabel(app.UIAxes_salida, 'Tiempo (s)')
            zlabel(app.UIAxes_salida, 'Z')
            app.UIAxes_entrada_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_entrada_2, 'Señal Entrada')
            xlabel(app.UIAxes_entrada_2, 'Tiempo (s)')
            zlabel(app.UIAxes_entrada_2, 'Z')
            app.UIAxes_salida_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_salida_2, 'Señal Salida')
            xlabel(app.UIAxes_salida_2, 'Tiempo (s)')
            zlabel(app.UIAxes_salida_2, 'Z')
            app.Hza500HzKnobLabel = uilabel(app.UIFigure);
            app.Hza500HzKnobLabel.Text = '0Hz a 500Hz';
            app.Hza500HzKnob = uiknob(app.UIFigure, 'continuous');
            app.Hza500HzKnob.Limits = [0 10];
            app.Hza500HzKnob.MajorTicks = [0 2 4 6 8 10];
            app.Hza500HzKnob.ValueChangingFcn = createCallbackFcn(app, @ganancia500hz, true);
            app.Hza500HzKnob.Value = 5;
            app.Hza2KhzKnobLabel = uilabel(app.UIFigure);
            app.Hza2KhzKnobLabel.Text = '500Hz a 2Khz';
            app.Hza2KhzKnob = uiknob(app.UIFigure, 'continuous');
            app.Hza2KhzKnob.Limits = [0 10];
            app.Hza2KhzKnob.MajorTicks = [0 2 4 6 8 10];
            app.Hza2KhzKnob.ValueChangingFcn = createCallbackFcn(app, @ganancia2khz, true);
            app.Hza2KhzKnob.Value = 5;
            app.Khza5KhzKnobLabel = uilabel(app.UIFigure);
            app.Khza5KhzKnobLabel.Text = '2Khz a 5Khz';
            app.Khza5KhzKnob = uiknob(app.UIFigure, 'continuous');
            app.Khza5KhzKnob.Limits = [0 10];
            app.Khza5KhzKnob.MajorTicks = [0 2 4 6 8 10];
            app.Khza5KhzKnob.ValueChangingFcn = createCallbackFcn(app, @ganancia5khz, true);
            app.Khza5KhzKnob.Value = 5;
            app.Khza10KhzKnobLabel = uilabel(app.UIFigure);
            app.Khza10KhzKnobLabel.Text = '5Khz a 10Khz';
            app.Khza10KhzKnob = uiknob(app.UIFigure, 'continuous');
            app.Khza10KhzKnob.Limits = [0 10];
            app.Khza10KhzKnob.MajorTicks = [0 2 4 6 8 10];
            app.Khza10KhzKnob.ValueChangingFcn = createCallbackFcn(app, @ganancia10khz, true);
            app.Khza10KhzKnob.Value = 5;
            app.Khza30KhzKnobLabel = uilabel(app.UIFigure);
            app.Khza30KhzKnobLabel.Text = '10Khz a 30Khz';
            app.Khza30KhzKnob = uiknob(app.UIFigure, 'continuous');
            app.Khza30KhzKnob.Limits = [0 10];
            app.Khza30KhzKnob.MajorTicks = [0 2 4 6 8 10];
            app.Khza30KhzKnob.ValueChangingFcn = createCallbackFcn(app, @ganancia30khz, true);
            app.Khza30KhzKnob.Value = 5;
            app.stp = uibutton(app.UIFigure, 'push');
            app.stp.ButtonPushedFcn = createCallbackFcn(app, @Stop, true);
            app.stp.Text = '◼';
            app.pse = uibutton(app.UIFigure, 'push');
            app.pse.ButtonPushedFcn = createCallbackFcn(app, @Pause, true);
            app.pse.Text = '❚❚';
            app.ply = uibutton(app.UIFigure, 'push');
            app.ply.ButtonPushedFcn = createCallbackFcn(app, @plylay, true);
            app.ply.Tag = 'Play';
            app.ply.Text = '▶';
            app.GRAFICARButton = uibutton(app.UIFigure, 'push');
            app.GRAFICARButton.ButtonPushedFcn = createCallbackFcn(app, @graficar, true);
            app.GRAFICARButton.Text = 'GRAFICAR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfazV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end