classdef generador_dig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        EcuacinEditField        matlab.ui.control.EditField
        EcuacinEditFieldLabel   matlab.ui.control.Label
        EscritaButton           matlab.ui.control.Button
        JuliannyCubillosCristianBonillaAlejandroRojasLabel  matlab.ui.control.Label
        Image4                  matlab.ui.control.Image
        Lamp                    matlab.ui.control.Lamp
        Switch                  matlab.ui.control.Switch
        Knob_3                  matlab.ui.control.DiscreteKnob
        Knob_2                  matlab.ui.control.DiscreteKnob
        Knob                    matlab.ui.control.DiscreteKnob
        TRIANGULARButton        matlab.ui.control.Button
        CUADRADAButton          matlab.ui.control.Button
        SENOButton              matlab.ui.control.Button
        OFFSETLabel             matlab.ui.control.Label
        AMPLITUDLabel           matlab.ui.control.Label
        FRECUENCIALabel         matlab.ui.control.Label
        GENERADORDESEALESLabel  matlab.ui.control.Label
        Image3                  matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        Image                   matlab.ui.control.Image
        g2                      matlab.ui.control.UIAxes
        g1                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        encendido=0;
        x=0;
        frecuencia=0;
        amplitud=0;
        offset=0;
        valort; 

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value=="On"
                app.encendido=1;
                app.Lamp.Color="g";
            else 
                cla(app.g1);
                cla(app.g2);
                app.Lamp.Color="r";
                app.g1.XGrid="off";
                app.g1.YGrid="off";
                app.g2.XGrid="off";
                app.g2.YGrid="off";
                app.encendido=0;
            end
        end

        % Button pushed function: SENOButton
        function SENOButtonPushed(app, event)
        app.x=1;
        if app.encendido==1
                fc=app.frecuencia;
                Fs=fc*10;
                T=1/Fs;
                L=124;            
                t=(0:L-1)*T;
                y=((app.amplitud)*sin(2*pi*fc*t))+(app.offset);
                z=abs(fft(y)/(L/2));
                z=z(1:L/2);
                f=(0:(L/2)-1)*Fs/L;            
                plot(app.g1,t,y);
                plot(app.g2,f,z);            
                app.g1.XGrid="on";
                app.g1.YGrid="on";
                app.g2.XGrid="on";
                app.g2.YGrid="on";
                ylim(app.g1, [min(y) - 2, max(y) + 2]);
                ylim(app.g2, [min(z) - 1, max(z) + 1]); 
                xlim(app.g2, [min(f) - 1, max(f) + 1]);                  
        end
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            eva1 = app.Knob.Value;
            if eva1=="Off"
                app.frecuencia=0;
            end
            if eva1=="1Hz"
                app.frecuencia=1; 
            end
            if eva1=="10Hz"
                app.frecuencia=10; 
            end
            if eva1=="100Hz"
                app.frecuencia=100; 
            end            
            if eva1=="1KHz"
                app.frecuencia=1000; 
            end            
            if eva1=="10KHz"
                app.frecuencia=10000; 
            end            
            if eva1=="100KHz"
                app.frecuencia=100000; 
            end            
            if eva1=="1MHz"
                app.frecuencia=1000000; 
            end            
            if eva1=="10MHz"
                app.frecuencia=10000000; 
            end            
            if eva1=="100MHz"
                app.frecuencia=100000000;            
            end            
            if eva1=="1GHz"
                app.frecuencia=1000000000; 
            end            

            if app.encendido==1
            if app.x==1
                fc=app.frecuencia;
                Fs=fc*10;
                T=1/Fs;
                L=124;            
                t=(0:L-1)*T;
                y=((app.amplitud)*sin(2*pi*fc*t))+(app.offset);
                z=abs(fft(y)/(L/2));
                z=z(1:L/2);
                f=(0:(L/2)-1)*Fs/L;            
                plot(app.g1,t,y);
                plot(app.g2,f,z);            
                app.g1.XGrid="on";
                app.g1.YGrid="on";
                app.g2.XGrid="on";
                app.g2.YGrid="on";
                ylim(app.g1, [min(y) - 2, max(y) + 2]);
                ylim(app.g2, [min(z) - 1, max(z) + 1]);
                xlim(app.g2, [min(f) - 1, max(f) + 1]); 
    
            end
            if app.x==2
                f=app.frecuencia;
                fs=f*100;
                c=1/fs;
                j=1024;
                ti=(0:j-1)*c;
                yc=app.amplitud*square(2*pi*f*ti)+(app.offset);
                z1=abs(fft(yc)/(j/2));
                z1=z1(1:j/2);
                f1=(0:(j/2)-1)*fs/j;            
                plot(app.g2,f1,z1);                 
                plot(app.g1,ti,yc);
                app.g1.XGrid="on";
                app.g1.YGrid="on";
                app.g2.XGrid="on";
                app.g2.YGrid="on"; 
                ylim(app.g1, [min(yc) - 2, max(yc) + 2]);
                ylim(app.g2, [min(z1) - 1, max(z1) + 1]);
                xlim(app.g2, [min(f1) - 1, max(f1) + 1]); 
            end
            if app.x==3
                fre=app.frecuencia;
                fsa=fre*100;
                cr=1/fsa;
                q=524;
                tie=(0:q-1)*cr;
                yt=app.amplitud*sawtooth(2*pi*fre*tie,0.5)+(app.offset);
                z2=abs(fft(yt)/(q/2));
                z2=z2(1:q/2);
                f1=(0:(q/2)-1)*fsa/q;            
                plot(app.g2,f1,z2);  
                plot(app.g1,tie,yt);
                app.g1.XGrid="on";
                app.g1.YGrid="on";
                app.g2.XGrid="on";
                app.g2.YGrid="on";    
                ylim(app.g1, [min(yt) - 2, max(yt) + 2]);
                ylim(app.g2, [min(z2) - 1, max(z2) + 1]);
                xlim(app.g2, [min(f1) - 1, max(f1) + 1]); 
            end

            end
        end

        % Value changed function: Knob_2
        function Knob_2ValueChanged(app, event)
            eva2 = app.Knob_2.Value;
            if eva2=="Off"
                app.amplitud=0;
            end            
            if eva2=="1V"
                app.amplitud=1;
            end    
            if eva2=="2V"
                app.amplitud=2+0.1;
            end               
            if eva2=="4V"
                app.amplitud=4+0.2;
            end               
            if eva2=="6V"
                app.amplitud=6+0.3;
            end               
            if eva2=="8V"
                app.amplitud=8+0.4;
            end               
            if eva2=="10V"
                app.amplitud=10+0.45;
            end                
            if eva2=="12V"
                app.amplitud=12+0.6;
            end                
            if eva2=="14V"
                app.amplitud=14+0.65;
            end                
            if eva2=="16V"
                app.amplitud=16+0.7;
            end                
            if eva2=="18V"
                app.amplitud=18+0.8;
            end                
            if eva2=="20V"
                app.amplitud=20+0.9;
            end                 

        if app.encendido==1
            if app.x==1
                fc=app.frecuencia;
                Fs=fc*10;
                T=1/Fs;
                L=124;            
                t=(0:L-1)*T;
                y=((app.amplitud)*sin(2*pi*fc*t))+(app.offset);
                z=abs(fft(y)/(L/2));
                z=z(1:L/2);
                f=(0:(L/2)-1)*Fs/L;            
                plot(app.g1,t,y);
                plot(app.g2,f,z);            
                app.g1.XGrid="on";
                app.g1.YGrid="on";
                app.g2.XGrid="on";
                app.g2.YGrid="on";
                ylim(app.g1, [min(y) - 2, max(y) + 2]);
                ylim(app.g2, [min(z) - 1, max(z) + 1]); 
                xlim(app.g2, [min(f) - 1, max(f) + 1]); 
               
            end
            if app.x==2
                f=app.frecuencia;
                fs=f*100;
                c=1/fs;
                j=1024;
                ti=(0:j-1)*c;
                yc=app.amplitud*square(2*pi*f*ti)+(app.offset);
                z1=abs(fft(yc)/(j/2));
                z1=z1(1:j/2);
                f1=(0:(j/2)-1)*fs/j;            
                plot(app.g2,f1,z1);                 
                plot(app.g1,ti,yc);
                app.g1.XGrid="on";
                app.g1.YGrid="on";
                app.g2.XGrid="on";
                app.g2.YGrid="on";  
                ylim(app.g1, [min(yc) - 2, max(yc) + 2]);
                ylim(app.g2, [min(z1) - 1, max(z1) + 1]);
                xlim(app.g2, [min(f1) - 1, max(f1) + 1]); 
            end
            if app.x==3
                fre=app.frecuencia;
                fsa=fre*100;
                cr=1/fsa;
                q=524;
                tie=(0:q-1)*cr;
                yt=app.amplitud*sawtooth(2*pi*fre*tie,0.5)+(app.offset);
                z2=abs(fft(yt)/(q/2));
                z2=z2(1:q/2);
                f1=(0:(q/2)-1)*fsa/q;            
                plot(app.g2,f1,z2);  
                plot(app.g1,tie,yt);
                app.g1.XGrid="on";
                app.g1.YGrid="on";
                app.g2.XGrid="on";
                app.g2.YGrid="on";   
                ylim(app.g1, [min(yt) - 2, max(yt) + 2]);
                ylim(app.g2, [min(z2) - 1, max(z2) + 1]);  
                xlim(app.g2, [min(f) - 1, max(f) + 1]); 
            end

        end            
        
        end

        % Value changed function: Knob_3
        function Knob_3ValueChanged(app, event)
            eva3 = app.Knob_3.Value;
            if eva3=="Off"
                app.offset=0;
            end  
            if eva3=="1V"
                app.offset=1;
            end 
            if eva3=="2V"
                app.offset=2;
            end              
            if eva3=="3V"
                app.offset=3;
            end             
            if eva3=="4V"
                app.offset=4;
            end            
            if eva3=="5V"
                app.offset=5;
            end             
            if eva3=="6V"
                app.offset=6;
            end             
            if eva3=="7V"
                app.offset=7;
            end             
            if eva3=="8V"
                app.offset=8;
            end             
            if eva3=="9V"
                app.offset=9;
            end             
            if eva3=="10V"
                app.offset=10;
            end              
            if app.encendido==1
            if app.x==1
                fc=app.frecuencia;
                Fs=fc*10;
                T=1/Fs;
                L=124;            
                t=(0:L-1)*T;
                y=((app.amplitud)*sin(2*pi*fc*t))+(app.offset);
                z=abs(fft(y)/(L/2));
                z=z(1:L/2);
                f=(0:(L/2)-1)*Fs/L;            
                plot(app.g1,t,y);               
                plot(app.g2,f,z);            
                app.g1.XGrid="on";
                app.g1.YGrid="on";
                app.g2.XGrid="on";
                app.g2.YGrid="on";
                ylim(app.g1, [min(y) - 2, max(y) + 2]);
                ylim(app.g2, [min(z) - 1, max(z) + 2]);
                xlim(app.g2, [min(f) - 1, max(f) + 1]); 
               
            end
            if app.x==2
                f=app.frecuencia;
                fs=f*100;
                c=1/fs;
                j=1024;
                ti=(0:j-1)*c;
                yc=app.amplitud*square(2*pi*f*ti)+(app.offset);
                z1=abs(fft(yc)/(j/2));
                z1=z1(1:j/2);
                f1=(0:(j/2)-1)*fs/j;            
                plot(app.g2,f1,z1);                 
                plot(app.g1,ti,yc);
                app.g1.XGrid="on";
                app.g1.YGrid="on";
                app.g2.XGrid="on";
                app.g2.YGrid="on";             
                ylim(app.g1, [min(yc) - 2, max(yc) + 2]);
                ylim(app.g2, [min(z1) - 1, max(z1) + 1]); 
                xlim(app.g2, [min(f1) - 1, max(f1) + 1]); 
            end
            if app.x==3
                fre=app.frecuencia;
                fsa=fre*100;
                cr=1/fsa;
                q=524;
                tie=(0:q-1)*cr;
                yt=app.amplitud*sawtooth(2*pi*fre*tie,0.5)+(app.offset);
                z2=abs(fft(yt)/(q/2));
                z2=z2(1:q/2);
                f1=(0:(q/2)-1)*fsa/q;            
                plot(app.g2,f1,z2);  
                plot(app.g1,tie,yt);
                app.g1.XGrid="on";
                app.g1.YGrid="on";
                app.g2.XGrid="on";
                app.g2.YGrid="on";                
                ylim(app.g1, [min(yt) - 2, max(yt) + 2]);
                ylim(app.g2, [min(z2) - 1, max(z2) + 1]);
                xlim(app.g2, [min(f1) - 1, max(f1) + 1]); 
            end

            end            
        end

        % Button pushed function: CUADRADAButton
        function CUADRADAButtonPushed(app, event)
            app.x=2;
            if app.encendido==1           
                f=app.frecuencia;
                fs=f*100;
                c=1/fs;
                j=1024;
                ti=(0:j-1)*c;
                yc=app.amplitud*square(2*pi*f*ti)+(app.offset);
                z1=abs(fft(yc)/(j/2));
                z1=z1(1:j/2);
                f1=(0:(j/2)-1)*fs/j;            
                plot(app.g2,f1,z1);                 
                plot(app.g1,ti,yc);
                app.g1.XGrid="on";
                app.g1.YGrid="on";
                app.g2.XGrid="on";
                app.g2.YGrid="on";             
                ylim(app.g1, [min(yc) - 2, max(yc) + 2]);
                ylim(app.g2, [min(z1) - 1, max(z1) + 1]); 
                xlim(app.g2, [min(f1) - 1, max(f1) + 1]); 
            end            
        end

        % Button pushed function: TRIANGULARButton
        function TRIANGULARButtonPushed(app, event)
            app.x=3;
            if app.encendido==1                
                fre=app.frecuencia;
                fsa=fre*100;
                cr=1/fsa;
                q=524;
                tie=(0:q-1)*cr;
                yt=app.amplitud*sawtooth(2*pi*fre*tie,0.5)+(app.offset);
                z2=abs(fft(yt)/(q/2));
                z2=z2(1:q/2);
                f1=(0:(q/2)-1)*fsa/q;            
                plot(app.g2,f1,z2);  
                plot(app.g1,tie,yt);
                app.g1.XGrid="on";
                app.g1.YGrid="on";
                app.g2.XGrid="on";
                app.g2.YGrid="on";
                ylim(app.g1, [min(yt) - 2, max(yt) + 2]);
                %ylim(app.g2, [min(z2) - 1, max(z2) + 1]); 
                xlim(app.g2, [min(f1) - 1, max(f1) + 1]); 
            end            
        end

        % Button pushed function: EscritaButton
        function EscritaButtonPushed(app, event)
            t=(0:0.01:2*pi);
            u=@(tt) tt>=0;
            app.valort = app.EcuacinEditField.Value;
            disp(['Texto ingresado: ', app.valort]);
            yt=eval(app.valort);
            YT=fft(yt);
            n = length(yt);  % Número de puntos en y
            f1 = (0:n-1)*(1/(n*0.01));  % Vector de frecuencias
            Y_magnitude = abs(YT)/n;
            plot(app.g2,f1,Y_magnitude);  
            plot(app.g1,t,yt);
            app.g1.XGrid="on";
            app.g1.YGrid="on";
            app.g2.XGrid="on";
            app.g2.YGrid="on";
            ylim(app.g1, [min(yt) - 2, max(yt) + 2]);
            ylim(app.g2, [min(Y_magnitude) - 1, max(Y_magnitude) + 1]); 
            xlim(app.g2, [min(t) - 1, max(t) + 1]); 


        end

        % Value changed function: EcuacinEditField
        function EcuacinEditFieldValueChanged(app, event)
              
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.g1 = uiaxes(app.UIFigure);
            title(app.g1, 'SEÑAL GENERADA')
            xlabel(app.g1, 'Tiempo')
            ylabel(app.g1, 'Amplitud')
            zlabel(app.g1, 'Z')
            app.g2 = uiaxes(app.UIFigure);
            title(app.g2, 'ESPECTRO DE LA SEÑAL')
            xlabel(app.g2, 'Tiempo')
            ylabel(app.g2, 'Amplitud')
            zlabel(app.g2, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '1.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '4.jpg');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '3.png');
            app.GENERADORDESEALESLabel = uilabel(app.UIFigure);
            app.GENERADORDESEALESLabel.Text = 'GENERADOR DE SEÑALES';
            app.FRECUENCIALabel = uilabel(app.UIFigure);
            app.FRECUENCIALabel.Text = 'FRECUENCIA';
            app.AMPLITUDLabel = uilabel(app.UIFigure);
            app.AMPLITUDLabel.Text = 'AMPLITUD';
            app.OFFSETLabel = uilabel(app.UIFigure);
            app.OFFSETLabel.Text = 'OFFSET';
            app.SENOButton = uibutton(app.UIFigure, 'push');
            app.SENOButton.ButtonPushedFcn = createCallbackFcn(app, @SENOButtonPushed, true);
            app.SENOButton.Text = 'SENO';
            app.CUADRADAButton = uibutton(app.UIFigure, 'push');
            app.CUADRADAButton.ButtonPushedFcn = createCallbackFcn(app, @CUADRADAButtonPushed, true);
            app.CUADRADAButton.Text = 'CUADRADA';
            app.TRIANGULARButton = uibutton(app.UIFigure, 'push');
            app.TRIANGULARButton.ButtonPushedFcn = createCallbackFcn(app, @TRIANGULARButtonPushed, true);
            app.TRIANGULARButton.Text = 'TRIANGULAR';
            app.Knob = uiknob(app.UIFigure, 'discrete');
            app.Knob.Items = {'Off', '1Hz', '10Hz', '100Hz', '1KHz', '10KHz', '100KHz', '1MHz', '10MHz', '100MHz', '1GHz'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob_2 = uiknob(app.UIFigure, 'discrete');
            app.Knob_2.Items = {'Off', '1V', '2V', '4V', '6V', '8V', '10V', '12V', '14V', '16V', '18V', '20V'};
            app.Knob_2.ValueChangedFcn = createCallbackFcn(app, @Knob_2ValueChanged, true);
            app.Knob_3 = uiknob(app.UIFigure, 'discrete');
            app.Knob_3.Items = {'Off', '1V', '2V', '3V', '4V', '5V', '6V', '7V', '8V', '9V', '10V'};
            app.Knob_3.ValueChangedFcn = createCallbackFcn(app, @Knob_3ValueChanged, true);
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, '15-removebg-preview (3).png');
            app.JuliannyCubillosCristianBonillaAlejandroRojasLabel = uilabel(app.UIFigure);
            app.JuliannyCubillosCristianBonillaAlejandroRojasLabel.Text = {'Julianny Cubillos'; 'Cristian Bonilla'; 'Alejandro Rojas'};
            app.EscritaButton = uibutton(app.UIFigure, 'push');
            app.EscritaButton.ButtonPushedFcn = createCallbackFcn(app, @EscritaButtonPushed, true);
            app.EscritaButton.Text = 'Escrita ';
            app.EcuacinEditFieldLabel = uilabel(app.UIFigure);
            app.EcuacinEditFieldLabel.Text = 'Ecuación';
            app.EcuacinEditField = uieditfield(app.UIFigure, 'text');
            app.EcuacinEditField.ValueChangedFcn = createCallbackFcn(app, @EcuacinEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = generador_dig

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end